classdef modelosGananciasAdaptivas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ControlporPosicionamientodePolosusandoGananciasAdaptivasPanel  matlab.ui.container.Panel
        Panel              matlab.ui.container.Panel
        Label              matlab.ui.control.Label
        inercia            matlab.ui.control.Slider
        polinomial         matlab.ui.control.CheckBox
        arbol              matlab.ui.control.CheckBox
        bosque             matlab.ui.control.CheckBox
        svm                matlab.ui.control.CheckBox
        INERCIATOTALLabel  matlab.ui.control.Label
        fijas              matlab.ui.control.CheckBox
        SincargaLabel      matlab.ui.control.Label
        CargaMximaLabel    matlab.ui.control.Label
        actualizar         matlab.ui.control.Button
        respuesta          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global modelo_lineal_v
            global modelo_lineal_c
            global modelo_arbol_v
            global modelo_arbol_c
            global modelo_bosque_v
            global modelo_bosque_c
            global modelo_svr_v
            global modelo_svr_c
            
            %COLOCAR IMÁGEN DEL SPIDER BOT REFERENCIANDO LAS VARIABLES
            [ruta, nombre, extension] = fileparts(mfilename('fullpath'));
            
            rutaRPV = strcat(ruta,'\modelos\Motor_Regresion_Lineal_Velocidad.pkl');
            rutaRPC = strcat(ruta,'\modelos\Motor_Regresion_Lineal_Corriente.pkl');
            rutaADV = strcat(ruta,'\modelos\Motor_Regresion_Arbol_Velocidad.pkl');
            rutaADC = strcat(ruta,'\modelos\Motor_Regresion_Arbol_Corriente.pkl');
            rutaBAV = strcat(ruta,'\modelos\Motor_Regresion_Bosque_Velocidad.pkl');
            rutaBAC = strcat(ruta,'\modelos\Motor_Regresion_Bosque_Corriente.pkl');
            rutaSVV = strcat(ruta,'\modelos\Motor_Regresion_SVM_Velocidad.pkl');
            rutaSVC = strcat(ruta,'\modelos\Motor_Regresion_SVM_Corriente.pkl');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%% CARGAR MODELOS DE MACHINE LEARNING %%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            modelo_lineal_v = py.joblib.load(rutaRPV);
            modelo_arbol_v = py.joblib.load(rutaADV);
            modelo_bosque_v = py.joblib.load(rutaBAV);
            modelo_svr_v = py.joblib.load(rutaSVV);
            
            modelo_lineal_c = py.joblib.load(rutaRPC);
            modelo_arbol_c = py.joblib.load(rutaADC);
            modelo_bosque_c = py.joblib.load(rutaBAC);
            modelo_svr_c = py.joblib.load(rutaSVC);

            open_system("E5S_Prueba_Simulacion");
            
            app.fijas.Value = 1;
        end

        % Button pushed function: actualizar
        function actualizarButtonPushed(app, event)
            global modelo_lineal_v
            global modelo_lineal_c
            global modelo_arbol_v
            global modelo_arbol_c
            global modelo_bosque_v
            global modelo_bosque_c
            global modelo_svr_v
            global modelo_svr_c
            
            global tiempo
            global ackW
            global polW
            global arbW
            global bosW
            global svmW
            global out
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%% MODELO DEL MOTOR DC %%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %DEFINIMOS LAS CONSTANTES DEL MOTOR DC
            Va = 220;        % Voltaje de Alimentación - Voltios (V)
            R = 2.2;         % Resistencia de Armadura - Ohmios(Ohm)
            L = 6.3e-3;      % Inductancia de Armadura - Henrios(H)
            km = 1.78;       % Relacion Torque/Corriente - (Nm/A)
            ka = km;         % Relacion Voltaje/Velocidad - (V.s/rad)
            B = 0.015;       % Coeficiente de Fricción Viscosa - (Nm.s)

            wmax = 122;
            G = wmax;
            J_a = app.inercia.Value;      % Inercia del Motor - (Kg.m^2)

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%% OBTENCIÓN DE K POR ACKER %%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %DEFINIMOS LAS MATRICES COEFICIENTES DE LAS ECUACIONES DE ESPACIO-ESTADO
            Ap = [-R/L -(G*ka)/L; km/(G*J_a) -B/J_a];
            Bp = [Va/L; 0];
            Cp = [0 1];
            Dp = 0;

            %DEFINIMOS LOS PARÁMETROS DE RENDIMIENTO DE LA RESPUESTA DESEADA
            ts = 0.03;
            
            %HALLAMOS LOS POLOS NECESARIOS PARA ALCANZAR LOS REQUERIMIENTOS
            sigma = 3/ts;
            
            P1 = -sigma;
            P2 = -40*sigma;
            
            polos = [P1 P2];
            
            %HALLAMOS LA MATRIZ K DE LA LEY DE CONTROL MEDIANTE FÓRMULA DE ACKER
            K = acker(Ap,Bp,polos);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%% OBTENCIÓN DE K POR MACHINE LEARNING %%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            J_numpy = py.numpy.array(pyargs('object',[J_a, 0]));
            dataframe = py.pandas.DataFrame(pyargs('data',J_numpy));
            
            %REGRESIÓN LINEAL
            gradoV = 8;
            gradoC = 3;
            
            dataframeV = py.pandas.DataFrame();
            dataframeV = dataframeV.append(py.list(PolinomialFeatures(J_a,gradoV)));
            dataframeV = dataframeV.transpose();
            
            dataframeC = py.pandas.DataFrame();
            dataframeC = dataframeC.append(py.list(PolinomialFeatures(J_a,gradoC)));
            dataframeC = dataframeC.transpose();
            
            pred_lineal_c = modelo_lineal_c.predict(dataframeC).tolist();
            pred_lineal_v = modelo_lineal_v.predict(dataframeV).tolist();
            
            K_ml_pol(1) = pred_lineal_c{1}{1};
            K_ml_pol(2) = pred_lineal_v{1}{1};
            
            %ÁRBOL DE DECISIÓN REGRESIÓN
            pred_arbol_c = modelo_arbol_c.predict(dataframe).tolist();
            pred_arbol_v = modelo_arbol_v.predict(dataframe).tolist();
            
            K_ml_arb(1) = pred_arbol_c{1};
            K_ml_arb(2) = pred_arbol_v{1};
            
            %BOSQUE ALEATORIO REGRESIÓN
            pred_bosque_c = modelo_bosque_c.predict(dataframe).tolist();
            pred_bosque_v = modelo_bosque_v.predict(dataframe).tolist();
            
            K_ml_bos(1) = pred_bosque_c{1};
            K_ml_bos(2) = pred_bosque_v{1};
            
            %SUPPORT VECTOR MACHINE REGRESIÓN
            pred_svr_c = modelo_svr_c.predict(dataframe).tolist();
            pred_svr_v = modelo_svr_v.predict(dataframe).tolist();
            
            K_ml_svr(1) = pred_svr_c{1};
            K_ml_svr(2) = pred_svr_v{1};
            
            %SIMULAR EL MOTOR EN SIMULINK Y LIMPIAR LAS MATRICES DE TIEMPO Y VELOCIDAD
            
            in = Simulink.SimulationInput("E5S_Prueba_Simulacion");
            in = in.setVariable('Va',Va);
            in = in.setVariable('R',R);
            in = in.setVariable('L',L);
            in = in.setVariable('km',km);
            in = in.setVariable('ka',ka);
            in = in.setVariable('B',B);
            in = in.setVariable('J_a',J_a);
            in = in.setVariable('K',K);
            in = in.setVariable('K_ml_pol',K_ml_pol);
            in = in.setVariable('K_ml_arb',K_ml_arb);
            in = in.setVariable('K_ml_bos',K_ml_bos);
            in = in.setVariable('K_ml_svr',K_ml_svr);
            
            out = sim(in);
            
            clear tiempo ackW polW arbW bosW svmW
            
            %OBTENER LOS DATOS DE TIEMPO Y VELOCIDADES CON INERCIA SIN CARGA
            tiempo = out.pruebas.Time;               % Obtener la matriz de tiempos de la simulación
            ackW = out.pruebas.Data(:,1);          % Obtener la matriz de velocidades del sistema controlado
            polW = out.pruebas.Data(:,2);          % Obtener la matriz de velocidades del sistema controlado
            arbW = out.pruebas.Data(:,3);          % Obtener la matriz de velocidades del sistema controlado
            bosW = out.pruebas.Data(:,4);          % Obtener la matriz de velocidades del sistema controlado
            svmW = out.pruebas.Data(:,5);          % Obtener la matriz de velocidades del sistema controlado
            
            %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
            app.respuesta.NextPlot = 'replace';
            
            if app.fijas.Value == 1   
                plot(app.respuesta,tiempo,ackW,'k--','LineWidth',2, 'DisplayName', 'Ganancias Fijas');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            if app.polinomial.Value == 1   
                plot(app.respuesta,tiempo,polW,'b--','LineWidth', 2, 'DisplayName', 'Regresión Polinomial');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            if app.arbol.Value == 1   
                plot(app.respuesta,tiempo,arbW,'c--','LineWidth',2, 'DisplayName', 'Árbol de Decisión');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            if app.bosque.Value == 1   
                plot(app.respuesta,tiempo,bosW,'g--','LineWidth',2, 'DisplayName', 'Bosque Aleatorio');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end

            if app.svm.Value == 1   
                plot(app.respuesta,tiempo,svmW,'r--','LineWidth',2, 'DisplayName', 'Support Vector Machine');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            xlim(app.respuesta,[tiempo(1) tiempo(end)]); 
            ylim(app.respuesta,[-1 55]);
            app.respuesta.BackgroundColor = [0.30 0.75 0.93];
            app.respuesta.XGrid = 1;
            app.respuesta.YGrid = 1;
            legend(app.respuesta);
            
            function out = PolinomialFeatures(X1,degree)
                out = zeros(1,degree+1);
                for i = 1:degree+1
                    out(1, i) = X1^(i-1);
                end
            end
        end

        % Value changed function: fijas
        function fijasValueChanged(app, event)
            global tiempo
            global ackW
            global polW
            global arbW
            global bosW
            global svmW
            global out
            
            %OBTENER LOS DATOS DE TIEMPO Y VELOCIDADES CON INERCIA SIN CARGA
            tiempo = out.pruebas.Time;               % Obtener la matriz de tiempos de la simulación
            ackW = out.pruebas.Data(:,1);          % Obtener la matriz de velocidades del sistema controlado
            polW = out.pruebas.Data(:,2);          % Obtener la matriz de velocidades del sistema controlado
            arbW = out.pruebas.Data(:,3);          % Obtener la matriz de velocidades del sistema controlado
            bosW = out.pruebas.Data(:,4);          % Obtener la matriz de velocidades del sistema controlado
            svmW = out.pruebas.Data(:,5);          % Obtener la matriz de velocidades del sistema controlado
            
            %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
            app.respuesta.NextPlot = 'replace';
            
            if app.fijas.Value == 1   
                plot(app.respuesta,tiempo,ackW,'k--','LineWidth',2, 'DisplayName', 'Ganancias Fijas');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            if app.polinomial.Value == 1   
                plot(app.respuesta,tiempo,polW,'b--','LineWidth', 2, 'DisplayName', 'Regresión Polinomial');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            if app.arbol.Value == 1   
                plot(app.respuesta,tiempo,arbW,'c--','LineWidth',2, 'DisplayName', 'Árbol de Decisión');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            if app.bosque.Value == 1   
                plot(app.respuesta,tiempo,bosW,'g--','LineWidth',2, 'DisplayName', 'Bosque Aleatorio');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end

            if app.svm.Value == 1   
                plot(app.respuesta,tiempo,svmW,'r--','LineWidth',2, 'DisplayName', 'Support Vector Machine');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            xlim(app.respuesta,[tiempo(1) tiempo(end)]); 
            ylim(app.respuesta,[-1 55]);
            app.respuesta.BackgroundColor = [0.30 0.75 0.93];
            app.respuesta.XGrid = 1;
            app.respuesta.YGrid = 1;
            legend(app.respuesta);
        end

        % Value changed function: polinomial
        function polinomialValueChanged(app, event)
            global tiempo
            global ackW
            global polW
            global arbW
            global bosW
            global svmW
            global out
            
            %OBTENER LOS DATOS DE TIEMPO Y VELOCIDADES CON INERCIA SIN CARGA
            tiempo = out.pruebas.Time;               % Obtener la matriz de tiempos de la simulación
            ackW = out.pruebas.Data(:,1);          % Obtener la matriz de velocidades del sistema controlado
            polW = out.pruebas.Data(:,2);          % Obtener la matriz de velocidades del sistema controlado
            arbW = out.pruebas.Data(:,3);          % Obtener la matriz de velocidades del sistema controlado
            bosW = out.pruebas.Data(:,4);          % Obtener la matriz de velocidades del sistema controlado
            svmW = out.pruebas.Data(:,5);          % Obtener la matriz de velocidades del sistema controlado
            
            %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
            app.respuesta.NextPlot = 'replace';
            
            if app.fijas.Value == 1   
                plot(app.respuesta,tiempo,ackW,'k--','LineWidth',2, 'DisplayName', 'Ganancias Fijas');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            if app.polinomial.Value == 1   
                plot(app.respuesta,tiempo,polW,'b--','LineWidth', 2, 'DisplayName', 'Regresión Polinomial');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            if app.arbol.Value == 1   
                plot(app.respuesta,tiempo,arbW,'c--','LineWidth',2, 'DisplayName', 'Árbol de Decisión');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            if app.bosque.Value == 1   
                plot(app.respuesta,tiempo,bosW,'g--','LineWidth',2, 'DisplayName', 'Bosque Aleatorio');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end

            if app.svm.Value == 1   
                plot(app.respuesta,tiempo,svmW,'r--','LineWidth',2, 'DisplayName', 'Support Vector Machine');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            xlim(app.respuesta,[tiempo(1) tiempo(end)]); 
            ylim(app.respuesta,[-1 55]);
            app.respuesta.BackgroundColor = [0.30 0.75 0.93];
            app.respuesta.XGrid = 1;
            app.respuesta.YGrid = 1;
            legend(app.respuesta);
        end

        % Value changed function: arbol
        function arbolValueChanged(app, event)
            global tiempo
            global ackW
            global polW
            global arbW
            global bosW
            global svmW
            global out
            
            %OBTENER LOS DATOS DE TIEMPO Y VELOCIDADES CON INERCIA SIN CARGA
            tiempo = out.pruebas.Time;               % Obtener la matriz de tiempos de la simulación
            ackW = out.pruebas.Data(:,1);          % Obtener la matriz de velocidades del sistema controlado
            polW = out.pruebas.Data(:,2);          % Obtener la matriz de velocidades del sistema controlado
            arbW = out.pruebas.Data(:,3);          % Obtener la matriz de velocidades del sistema controlado
            bosW = out.pruebas.Data(:,4);          % Obtener la matriz de velocidades del sistema controlado
            svmW = out.pruebas.Data(:,5);          % Obtener la matriz de velocidades del sistema controlado
            
            %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
            app.respuesta.NextPlot = 'replace';
            
            if app.fijas.Value == 1   
                plot(app.respuesta,tiempo,ackW,'k--','LineWidth',2, 'DisplayName', 'Ganancias Fijas');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            if app.polinomial.Value == 1   
                plot(app.respuesta,tiempo,polW,'b--','LineWidth', 2, 'DisplayName', 'Regresión Polinomial');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            if app.arbol.Value == 1   
                plot(app.respuesta,tiempo,arbW,'c--','LineWidth',2, 'DisplayName', 'Árbol de Decisión');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            if app.bosque.Value == 1   
                plot(app.respuesta,tiempo,bosW,'g--','LineWidth',2, 'DisplayName', 'Bosque Aleatorio');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end

            if app.svm.Value == 1   
                plot(app.respuesta,tiempo,svmW,'r--','LineWidth',2, 'DisplayName', 'Support Vector Machine');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            xlim(app.respuesta,[tiempo(1) tiempo(end)]); 
            ylim(app.respuesta,[-1 55]);
            app.respuesta.BackgroundColor = [0.30 0.75 0.93];
            app.respuesta.XGrid = 1;
            app.respuesta.YGrid = 1;
            legend(app.respuesta);
        end

        % Value changed function: bosque
        function bosqueValueChanged(app, event)
            global tiempo
            global ackW
            global polW
            global arbW
            global bosW
            global svmW
            global out
            
            %OBTENER LOS DATOS DE TIEMPO Y VELOCIDADES CON INERCIA SIN CARGA
            tiempo = out.pruebas.Time;               % Obtener la matriz de tiempos de la simulación
            ackW = out.pruebas.Data(:,1);          % Obtener la matriz de velocidades del sistema controlado
            polW = out.pruebas.Data(:,2);          % Obtener la matriz de velocidades del sistema controlado
            arbW = out.pruebas.Data(:,3);          % Obtener la matriz de velocidades del sistema controlado
            bosW = out.pruebas.Data(:,4);          % Obtener la matriz de velocidades del sistema controlado
            svmW = out.pruebas.Data(:,5);          % Obtener la matriz de velocidades del sistema controlado
            
            %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
            app.respuesta.NextPlot = 'replace';
            
            if app.fijas.Value == 1   
                plot(app.respuesta,tiempo,ackW,'k--','LineWidth',2, 'DisplayName', 'Ganancias Fijas');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            if app.polinomial.Value == 1   
                plot(app.respuesta,tiempo,polW,'b--','LineWidth', 2, 'DisplayName', 'Regresión Polinomial');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            if app.arbol.Value == 1   
                plot(app.respuesta,tiempo,arbW,'c--','LineWidth',2, 'DisplayName', 'Árbol de Decisión');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            if app.bosque.Value == 1   
                plot(app.respuesta,tiempo,bosW,'g--','LineWidth',2, 'DisplayName', 'Bosque Aleatorio');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end

            if app.svm.Value == 1   
                plot(app.respuesta,tiempo,svmW,'r--','LineWidth',2, 'DisplayName', 'Support Vector Machine');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            xlim(app.respuesta,[tiempo(1) tiempo(end)]); 
            ylim(app.respuesta,[-1 55]);
            app.respuesta.BackgroundColor = [0.30 0.75 0.93];
            app.respuesta.XGrid = 1;
            app.respuesta.YGrid = 1;
            legend(app.respuesta);
        end

        % Value changed function: svm
        function svmValueChanged(app, event)
            global tiempo
            global ackW
            global polW
            global arbW
            global bosW
            global svmW
            global out
            
            %OBTENER LOS DATOS DE TIEMPO Y VELOCIDADES CON INERCIA SIN CARGA
            tiempo = out.pruebas.Time;               % Obtener la matriz de tiempos de la simulación
            ackW = out.pruebas.Data(:,1);          % Obtener la matriz de velocidades del sistema controlado
            polW = out.pruebas.Data(:,2);          % Obtener la matriz de velocidades del sistema controlado
            arbW = out.pruebas.Data(:,3);          % Obtener la matriz de velocidades del sistema controlado
            bosW = out.pruebas.Data(:,4);          % Obtener la matriz de velocidades del sistema controlado
            svmW = out.pruebas.Data(:,5);          % Obtener la matriz de velocidades del sistema controlado
            
            %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
            app.respuesta.NextPlot = 'replace';
            
            if app.fijas.Value == 1   
                plot(app.respuesta,tiempo,ackW,'k--','LineWidth',2, 'DisplayName', 'Ganancias Fijas');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            if app.polinomial.Value == 1   
                plot(app.respuesta,tiempo,polW,'b--','LineWidth', 2, 'DisplayName', 'Regresión Polinomial');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            if app.arbol.Value == 1   
                plot(app.respuesta,tiempo,arbW,'c--','LineWidth',2, 'DisplayName', 'Árbol de Decisión');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            if app.bosque.Value == 1   
                plot(app.respuesta,tiempo,bosW,'g--','LineWidth',2, 'DisplayName', 'Bosque Aleatorio');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end

            if app.svm.Value == 1   
                plot(app.respuesta,tiempo,svmW,'r--','LineWidth',2, 'DisplayName', 'Support Vector Machine');
                
                %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
                app.respuesta.NextPlot = 'add';
            end
            
            xlim(app.respuesta,[tiempo(1) tiempo(end)]); 
            ylim(app.respuesta,[-1 55]);
            app.respuesta.BackgroundColor = [0.30 0.75 0.93];
            app.respuesta.XGrid = 1;
            app.respuesta.YGrid = 1;
            legend(app.respuesta);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ControlporPosicionamientodePolosusandoGananciasAdaptivasPanel = uipanel(app.UIFigure);
            app.ControlporPosicionamientodePolosusandoGananciasAdaptivasPanel.Title = 'Control por Posicionamiento de Polos usando Ganancias Adaptivas';
            app.Panel = uipanel(app.ControlporPosicionamientodePolosusandoGananciasAdaptivasPanel);
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.inercia = uislider(app.Panel);
            app.inercia.Limits = [0.0236 0.08];
            app.inercia.Tag = 'inercia';
            app.inercia.Value = 0.0236;
            app.polinomial = uicheckbox(app.Panel);
            app.polinomial.ValueChangedFcn = createCallbackFcn(app, @polinomialValueChanged, true);
            app.polinomial.Text = 'Regresión Polinomial';
            app.arbol = uicheckbox(app.Panel);
            app.arbol.ValueChangedFcn = createCallbackFcn(app, @arbolValueChanged, true);
            app.arbol.Text = 'Árbol de Decisión';
            app.bosque = uicheckbox(app.Panel);
            app.bosque.ValueChangedFcn = createCallbackFcn(app, @bosqueValueChanged, true);
            app.bosque.Text = 'Bosque Aleatorio';
            app.svm = uicheckbox(app.Panel);
            app.svm.ValueChangedFcn = createCallbackFcn(app, @svmValueChanged, true);
            app.svm.Text = 'Support Vector Machine';
            app.INERCIATOTALLabel = uilabel(app.Panel);
            app.INERCIATOTALLabel.Text = 'INERCIA TOTAL';
            app.fijas = uicheckbox(app.Panel);
            app.fijas.ValueChangedFcn = createCallbackFcn(app, @fijasValueChanged, true);
            app.fijas.Text = 'Ganancias Fijas';
            app.SincargaLabel = uilabel(app.Panel);
            app.SincargaLabel.Text = 'Sin carga';
            app.CargaMximaLabel = uilabel(app.Panel);
            app.CargaMximaLabel.Text = 'Carga Máxima';
            app.actualizar = uibutton(app.Panel, 'push');
            app.actualizar.ButtonPushedFcn = createCallbackFcn(app, @actualizarButtonPushed, true);
            app.actualizar.Text = 'Actualizar Inercia';
            app.respuesta = uiaxes(app.ControlporPosicionamientodePolosusandoGananciasAdaptivasPanel);
            title(app.respuesta, 'Title')
            xlabel(app.respuesta, 'X')
            ylabel(app.respuesta, 'Y')
            app.respuesta.XLim = [0 1];
            app.respuesta.YLim = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modelosGananciasAdaptivas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end